classdef gui_sondeneme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RespirationModeButton          matlab.ui.control.Button
        RespirationRateperMinuteEditField  matlab.ui.control.NumericEditField
        RespirationRateperMinuteLabel  matlab.ui.control.Label
        PCGRateperMinuteEditField      matlab.ui.control.NumericEditField
        RespirationRate1minLabel       matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
        DateDatePickerLabel            matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global a;

if ~isempty(instrfind)
    fclose(instrfind);
    delete(instrfind)
end 
Fsample=1000;
Tsample=1/Fsample;
fnyquist=200;

cons_int_low=1;
cons_int_high=250;
recordingsignal=1;
recordingsignal2=200;
recordingsignal3=0;
xxx1limit=1;
xxx2limit=400;

a=arduino('com11','uno','BaudRate',115200);
interv=inf;
init_time=1;

Fsample=1000;
    N=3;
    Fpass=200;
    Apass=1;
    d = fdesign.lowpass('N,Fp,Ap',N,Fpass,Apass,Fsample);
    Hd=design(d,'cheby1');
    fftx=0:1:fnyquist-1;

x=0;
while init_time<interv
b=readVoltage(a,'A0');

x=[x,b];
xxx1=x;

    y=filter(Hd,xxx1);


plot(app.UIAxes2,y)
xlim(app.UIAxes2,[xxx1limit,xxx2limit])


plot(app.UIAxes,xxx1);
xlim(app.UIAxes,[xxx1limit,xxx2limit])


init_time=init_time+1;


if init_time==xxx2limit


fftsignal=x(1:recordingsignal2);
fftsignal2=y(1:recordingsignal2);
        
       plot(app.UIAxes4,fftx,abs(fft(fftsignal(1+recordingsignal3:recordingsignal2))))
       
       plot(app.UIAxes3,fftx,abs(fft(fftsignal2((1+recordingsignal3:recordingsignal2)))))
        

        recordingsignal = recordingsignal + 200;
        recordingsignal2 = recordingsignal2 + 200;
        recordingsignal3=recordingsignal2-200;


x_x625=numel(findpeaks((y(xxx1limit:xxx2limit)),"MinPeakProminence",0.3));

app.PCGRateperMinuteEditField.Value=4*x_x625;


xxx1limit=xxx1limit+400;
xxx2limit=xxx2limit+400;
end


end


        end

        % Button pushed function: RespirationModeButton
        function RespirationModeButtonPushed(app, event)
            global a;

if ~isempty(instrfind)
    fclose(instrfind);
    delete(instrfind)
end 
Fsample=1600;
Tsample=1/Fsample;
fnyquist=800;

cons_int_low=1;
cons_int_high=250;
recordingsignal=1;
recordingsignal2=800;
recordingsignal3=0;
xxx1limit=1;
xxx2limit=800;

a=arduino('com11','uno','BaudRate',115200);
interv=inf;
init_time=1;

Fsample=1600;
    N=3;
    Fpass=800;
    Apass=1;
    d = fdesign.lowpass('N,Fp,Ap',N,Fpass,Apass,Fsample);
    Hd=design(d,'cheby1');
    fftx=0:1:fnyquist-1;

x=0;
while init_time<interv
b=readVoltage(a,'A0');

x=[x,b];
xxx1=x;

    y=filter(Hd,xxx1);


plot(app.UIAxes2,y)
xlim(app.UIAxes2,[xxx1limit,xxx2limit])


plot(app.UIAxes,xxx1);
xlim(app.UIAxes,[xxx1limit,xxx2limit])


init_time=init_time+1;


if init_time==xxx2limit


fftsignal=x(1:recordingsignal2);
fftsignal2=y(1:recordingsignal2);
        
       plot(app.UIAxes4,fftx,abs(fft(fftsignal(1+recordingsignal3:recordingsignal2))))
       
       plot(app.UIAxes3,fftx,abs(fft(fftsignal2((1+recordingsignal3:recordingsignal2)))))


        recordingsignal2 = recordingsignal2 + 800;
        recordingsignal3=recordingsignal2-800;


x_x625=numel(findpeaks((y(xxx1limit:xxx2limit)),"MinPeakProminence",0.6));

app.RespirationRateperMinuteEditField.Value=4*x_x625;


xxx1limit=xxx1limit+800;
xxx2limit=xxx2limit+800;
end


end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real Time PCG Signal (400) ')
            xlabel(app.UIAxes, 'Time Interval')
            ylabel(app.UIAxes, 'Amplitude (in V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0.5 3];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered Real Time PCG Signal (250) ')
            xlabel(app.UIAxes2, 'Time Interval')
            ylabel(app.UIAxes2, 'Amplitude (in V)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [0.5 3];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'FFT of the Real Time PCG Signal')
            xlabel(app.UIAxes4, 'Frequency')
            ylabel(app.UIAxes4, 'Amplitude (in V)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.YLim = [0 10];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtered PCG Signal for 250 Time Interval')
            xlabel(app.UIAxes3, 'Time Interval')
            ylabel(app.UIAxes3, 'Amplitude (in V)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YLim = [0 10];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'indir (1).jpeg');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.DateDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.DateDatePicker.Value = datetime([2024 1 21]);
            app.RespirationRate1minLabel = uilabel(app.UIFigure);
            app.RespirationRate1minLabel.Text = 'PCG Rate per Minute';
            app.PCGRateperMinuteEditField = uieditfield(app.UIFigure, 'numeric');
            app.RespirationRateperMinuteLabel = uilabel(app.UIFigure);
            app.RespirationRateperMinuteLabel.Text = '   Respiration Rate per Minute ';
            app.RespirationRateperMinuteEditField = uieditfield(app.UIFigure, 'numeric');
            app.RespirationModeButton = uibutton(app.UIFigure, 'push');
            app.RespirationModeButton.ButtonPushedFcn = createCallbackFcn(app, @RespirationModeButtonPushed, true);
            app.RespirationModeButton.Text = 'Respiration Mode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_sondeneme

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end